classdef TDM_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ManagementTab                   matlab.ui.container.Tab
        ExportAssessmentButton          matlab.ui.control.StateButton
        DistributePreferencesButton     matlab.ui.control.Button
        SearchTagsInclude               matlab.ui.control.EditField
        EditFieldLabel                  matlab.ui.control.Label
        ExcludeLabel                    matlab.ui.control.Label
        IncludeLabel                    matlab.ui.control.Label
        SearchTagsExclude               matlab.ui.control.EditField
        TransferCalibrationFilesButton  matlab.ui.control.Button
        TransferFilesButton             matlab.ui.control.Button
        AssessTargetButton              matlab.ui.control.Button
        TableFiles                      matlab.ui.control.Table
        FindVideoFilesButton            matlab.ui.control.Button
        Target                          matlab.ui.control.EditField
        SelectTargetDirectoryButton     matlab.ui.control.Button
        Source                          matlab.ui.control.EditField
        SelectSourceDirectoryButton     matlab.ui.control.Button
        AssessmentTab                   matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        StartGeneralAssessmentButton    matlab.ui.control.Button
        MissingParameterFilesLabel      matlab.ui.control.Label
        MissingCalibrationsLabel        matlab.ui.control.Label
        MissingVideoFilesLabel          matlab.ui.control.Label
        TablePreferences                matlab.ui.control.Table
        TableCalibration                matlab.ui.control.Table
        TableVideo                      matlab.ui.control.Table
        AssessFrameCountsButton         matlab.ui.control.Button
        UnequalFrameCountLabel          matlab.ui.control.Label
        TableFrameCount                 matlab.ui.control.Table
        PreferencesTab                  matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        RemoveButton                    matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        Log                             matlab.ui.control.TextArea
    end

    
    methods (Access = private)
        
        function files = filesearch(app, files, m_directory)

            m_files = dir(m_directory);
            m_tags_include = app.SearchTagsInclude.Value;
            m_tags_exclude = app.SearchTagsExclude.Value;
            if ~isempty(m_tags_include)
                m_tags_include = textscan(m_tags_include,'%s','Delimiter',',');
            end
            if ~isempty(m_tags_exclude)
                m_tags_exclude = textscan(m_tags_exclude,'%s','Delimiter',',');
            end

            for i = 3:length(m_files)
                if isfolder([m_files(i).folder '\' m_files(i).name]) && ~strcmp(m_files(i).name(1), '.')
                    files = filesearch(app, files, [m_files(i).folder '\' m_files(i).name]);
                elseif isfile([m_files(i).folder '\' m_files(i).name])
                    if isempty(fieldnames(files))
                        n = 0;
                        files.name = {};
                        files.folder = {};
                        files.bytes = [];
                    else
                        n = length(files.name);
                    end
                    [~, ~, ext] = fileparts(m_files(i).name);
                    % Check if there are tags available and if they are
                    % determine which files have them.
                    if ~isempty(m_tags_include)
                        bol_include = cellfun(@(s)find(~cellfun('isempty', strfind({m_files(i).name},s))),m_tags_include{1},'uni',0);
                    else
                        bol_include = '';
                    end
                    % Determine if there are tags available and if they are
                    % determine which files do not have them.
                    if ~isempty(m_tags_exclude)
                        bol_exclude = cellfun(@(s)find(~cellfun('isempty', strfind({m_files(i).name},s))),m_tags_exclude{1},'uni',0);
                    else
                        bol_exclude = '';
                    end
                    % If the file matches the selected file extension and
                    % the tag requirements it will be added to the table.
                    if (strcmp(ext, ['.' 'avi']) && ((strcmp(m_tags_include, '') || ~any(cellfun(@isempty,bol_include))) && (strcmp(m_tags_exclude, '') || ~any(~cellfun(@isempty,bol_exclude)))))
                        files.name{n+1, 1} = m_files(i).name;
                        files.folder{n+1, 1} = m_files(i).folder;
                        files.bytes(n+1, 1) = m_files(i).bytes/1000;
                    end
                end
                % Every ten files update the table.
                if rem(length(m_files),10) == 0
                    app.TableFiles.Data = struct2table(files);
                    pause(0.001)
                    scroll(app.Log,'bottom')
                    drawnow
                end
            end
        end
        
        function printLog(app, code, newText)
            text = app.Log.Value;
            switch code
                case '001'
                    text{length(text) + 1,1} = ['001 file search started'];
                case '002'
                    text{length(text) + 1,1} = ['002 file search complete, ' num2str(newText{1}) ' files found, ' num2str(ceil(newText{2}*1e-6)) ' MB total'];
                case '003'
                    text{length(text) + 1,1} = ['003 no files to assess'];printLog(app, '009', '')
                case '009'
                    text{length(text) + 1,1} = ['009 video transfer started'];
                case '004'
                    text{length(text) + 1,1} = ['004 ' newText '% of files already exist and will be skipped in any transfer'];
                case '005'
                    text{length(text) + 1,1} = ['005 no files found in the selected directory'];
                case '006'
                    text{length(text) + 1,1} = ['006 ' newText '% of files have a calibration file'];
                case '007'
                    text{length(text) + 1,1} = ['007 ' newText '% of new files have been transferred'];
                case '008'
                    text{length(text) + 1,1} = ['008 target assessment started'];
                case '010'
                    text{length(text) + 1,1} = ['010 calibration transfer started'];
                case '011'
                    text{length(text) + 1,1} = ['011 calibration transfer complete'];
                case '012'
                    text{length(text) + 1,1} = ['012 ' newText];
                case '013'
                    text{length(text) + 1,1} = ['013 ' newText '% of files have a preferences file'];
                case '014'
                    text{length(text) + 1,1} = ['014 no preferences have been selected for distribution'];
                case '015'
                    text{length(text) + 1,1} = ['015 ' newText '% of preferences have been distributed'];
                case '016'
                    text{length(text) + 1,1} = ['016 starting frame count assessment'];
                case '017'
                    text{length(text) + 1,1} = ['017 ' newText];
                case '018'
                    text{length(text) + 1,1} = ['018 frame count assessment complete'];
            end
            app.Log.Value = text;
            scroll(app.Log,'bottom')
            drawnow
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FindVideoFilesButton
        function FindVideoFilesButtonPushed(app, event)
            % Pull the selected directory from the app text box.
            m_directory = app.Source.Value;
            
            % Initialize an empty structure for the object to store the
            % file information.
            files = struct;
            % Perform recursive file search.
            printLog(app, '001', {''})
            files = filesearch(app, files, m_directory);
            if isempty(fieldnames(files))
                printLog(app, '005')
                return
            end

            i = 1;
            while i <= length(files.name)
                ind = strfind(files.folder{i}, '\');
                if strcmp(files.folder{i}(ind(end) + 1:end), files.name{i}(1:end - 4))
                    files.folder(i) = [];
                    files.name(i) = [];
                    files.bytes(i) = [];
                else
                    i = i + 1;
                end
            end

            printLog(app, '002', {length(files.name);sum(files.bytes)})
            % Put the files found into the app table.
            app.TableFiles.Data = struct2table(files);
            app.TableFiles.ColumnName = fieldnames(files);

        end

        % Button pushed function: SelectSourceDirectoryButton
        function SelectSourceDirectoryButtonPushed(app, event)
            path = uigetdir;
            app.Source.Value = path;
        end

        % Button pushed function: SelectTargetDirectoryButton
        function SelectTargetDirectoryButtonPushed(app, event)
            path = uigetdir;
            app.Target.Value = path;
        end

        % Button pushed function: AssessTargetButton
        function AssessTargetButtonPushed(app, event)
            
            files = app.TableFiles.Data;

            if isempty(files)
                printLog(app, '003', '')
                return
            end

            printLog(app, '008', '')

            files.calibration = zeros(height(files), 1);
            files.preferences = zeros(height(files), 1);
            files.exist = zeros(height(files), 1);

            for i = 1:height(files)
                
                ind_serial = strfind(files.name{i}, '_');
                serial = files.name{i}(ind_serial(end) + 1:end - 4);
                ind_miqus = strfind(files.name{i}, 'Miqus');
                recording = files.name{i}(1:ind_miqus - 2);
                if isfile([app.Target.Value  files.folder{i}(length(app.Source.Value) + 1:end) '\' recording '\' serial '\' serial '.avi'])
                    files.exist(i) = 1;

                    files_cal = dir([app.Target.Value  files.folder{i}(length(app.Source.Value) + 1:end) '\' recording '\']);
                    if any(contains({files_cal.name}, 'qca.txt'))
                        files.calibration(i) = 1;
                    else
                        files.calibration(i) = 0;
                    end
                    if any(contains({files_cal.name}, '.pxt'))
                        files.preferences(i) = 1;
                    else
                        files.preferences(i) = 0;
                    end

                else
                    files.exist(i) = 0;
                end
            end

            printLog(app, '004', num2str(floor(sum(files.exist)/height(files)*100)))
            printLog(app, '006', num2str(floor(sum(files.calibration)/height(files)*100)))
            printLog(app, '013', num2str(floor(sum(files.preferences)/height(files)*100)))

            app.TableFiles.Data = files;
            app.TableFiles.ColumnName = fieldnames(files);


        end

        % Button pushed function: TransferFilesButton
        function TransferFilesButtonPushed(app, event)

            printLog(app, '009', '')

            files = app.TableFiles.Data;

            status = zeros(height(files), 1);
            for i = 1:height(files)
                if ~files.exist(i)
                    ind_serial = strfind(files.name{i}, '_');
                    serial = files.name{i}(ind_serial(end) + 1:end - 4);
                    ind_miqus = strfind(files.name{i}, 'Miqus');
                    recording = files.name{i}(1:ind_miqus - 2);
                    if exist([app.Target.Value  files.folder{i}(length(app.Source.Value) + 1:end) '\' recording '\' serial], 'dir')
                        [status(i)] = copyfile([files.folder{i} '\' files.name{i}], [app.Target.Value  files.folder{i}(length(app.Source.Value) + 1:end) '\' recording '\' serial '\' serial '.avi']);
                    else
                        mkdir([app.Target.Value  files.folder{i}(length(app.Source.Value) + 1:end) '\' recording '\' serial])
                        [status(i)] = copyfile([files.folder{i} '\' files.name{i}], [app.Target.Value  files.folder{i}(length(app.Source.Value) + 1:end) '\' recording '\' serial '\' serial '.avi']);
                    end
                end
                if rem(i, 10) == 0
                    printLog(app, '012', [num2str(i) ' of ' num2str(height(files)) ' files have been transferred'])
                end
            end

            printLog(app, '007', num2str(sum(status & ~files.exist)/height(files)*100))
        end

        % Button pushed function: TransferCalibrationFilesButton
        function TransferCalibrationFilesButtonPushed(app, event)

            printLog(app, '010', '')

            files = app.TableFiles.Data;

            % This assumes the system is Qualisys. At the level of the Data
            % folder there will be another folder called Calibration. This
            % is where the calibrations are stored as qca and txt files.
            ind_cal = strfind(app.Source.Value, '\Data');
            files_cal = dir([app.Source.Value(1:ind_cal(end)) 'Calibrations']);
            % Remove any files that are not exported text files.
            files_cal(find(~contains({files_cal.name}, 'qca.txt')), :) = [];

            for i = 1:height(files)
                if ~files.calibration(i)
                    % This first part looks for the data in files saved
                    % with a format of P001_20220923_w_001.qtm.
                    ind_d = strfind(files.name{i}, '_');
                    d = files.name{i}(ind_d(1) + 1:ind_d(2) - 1);
                    ind_cal2 = find(contains({files_cal.name}, d));
                    % If the data is not in the file name it is assumed to
                    % be the last directory in the video file locations. So
                    % R:\PROJECTS\WESENS\Data\P001\20220923.
                    if isempty(ind_cal2)
                        ind_d = strfind(files.folder{i}, '\');
                        d = files.folder{i}(ind_d(end) + 1:end);
                        ind_cal2 = find(contains({files_cal.name}, d));
                    end
                    if ~isempty(ind_cal2)
                        cal = readstruct([files_cal(ind_cal2).folder '\' files_cal(ind_cal2).name], 'FileType', 'xml');

                        % Remove any 210c Oqus cameras from Qualisys.
                        cameras = {cal.cameras.camera(:).modelAttribute}';
                        ind_c = 1;
                        ind_m = 1;
                        while ind_c < length(cal.cameras.camera)
                            if contains(cal.cameras.camera(ind_c).modelAttribute, '210')
                                cal.cameras.camera(ind_c) = [];
                            else
                                ind_c = ind_c + 1;
                            end
                        end

                        % Theia will be unable to load calibration
                        % information from Qualisys if the hybrid Miqus
                        % cameras have inconsistent fields-of-view between
                        % their marker and video settings.
                        for j = 1:length(cal.cameras.camera)
                            if contains(cal.cameras.camera(j).modelAttribute, 'Miqus')
                                cal.cameras.camera(j).fov_marker.rightAttribute = cal.cameras.camera(j).fov_video.rightAttribute;
                                cal.cameras.camera(j).fov_marker.bottomAttribute = cal.cameras.camera(j).fov_video.bottomAttribute;
                                cal.cameras.camera(j).fov_marker_max.rightAttribute = cal.cameras.camera(j).fov_video_max.rightAttribute;
                                cal.cameras.camera(j).fov_marker_max.bottomAttribute = cal.cameras.camera(j).fov_video_max.bottomAttribute;
                            end
                        end

                        ind_serial = strfind(files.name{i}, '_');
                        serial = files.name{i}(ind_serial(end) + 1:end - 4);
                        ind_miqus = strfind(files.name{i}, 'Miqus');
                        recording = files.name{i}(1:ind_miqus - 2);
                        writestruct(cal, [app.Target.Value  files.folder{i}(length(app.Source.Value) + 1:end) '\' recording '\' files_cal(ind_cal2).name], 'FileType', 'xml', 'StructNodeName', 'calibration')
                    end

                end
            end

            printLog(app, '011', '')

        end

        % Button pushed function: StartGeneralAssessmentButton
        function StartGeneralAssessmentButtonPushed(app, event)
            files = app.TableFiles.Data;

            % Empty the table.
            app.TableVideo.Data = [];
            % Use the existing assessment to identify the missing files.
            files_val.missing = files.name(~files.exist);
            % Update the table.
            app.TableVideo.Data = struct2table(files_val);
            app.TableVideo.ColumnName = fieldnames(files_val);

            % Iterate through the files to find missing calibration files.
            app.TableCalibration.Data = [];
            d = '';
            ind = 1;
            cal_val.date = [];
            cal_val.subject = [];
            for i = 1:height(files)
                if files.calibration(i) == 0
                    % This is used to find the subject identifier and
                    % session date within an assumed organization.
                    ind_d = strfind(files.folder{i}, '\');
                    if ind == 1 || ~strcmp(cal_val.date{ind - 1,1}, files.folder{i}(ind_d(end) + 1:end))
                        % Only the subject identifier and the date will be
                        % displayed in the table.
                        cal_val.date{ind, 1} = files.folder{i}(ind_d(end) + 1:end);
                        cal_val.subject{ind, 1} = files.folder{i}(ind_d(end - 1) + 1:ind_d(end) - 1);
                        ind = ind + 1;
                    end
                end
            end
            % Update the table.
            app.TableCalibration.Data = struct2table(cal_val);
            app.TableCalibration.ColumnName = fieldnames(cal_val);

            % Empty the preferences table.
            app.TablePreferences.Data = [];
            % Identify all the video files missing preferences.
            files_prf.missing = files.name(~files.preferences);
            % When finding the unique recordings it is assumed the word
            % Micus is appended to all of the video file names.
            ind = strfind(files_prf.missing, 'Miqus');
            for i = 1:length(ind)
                % Everything in the file name from one character before
                % Micus to the end is removed.
                files_prf.missing{i}(ind{i} - 1:end) = [];
            end
            % Only the unique recording names need to be saved.
            files_prf.missing = unique(files_prf.missing);
            app.TablePreferences.Data = struct2table(files_prf);
            app.TablePreferences.ColumnName = fieldnames(files_prf);


        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            [filename, pathname] = uigetfile('.pxt', 'Select a preference file');
            if filename == 0
                return
            end
            files_preferences = app.UITable.Data;
            if isempty(files_preferences)
                files_preferences.file{1} = filename;
                files_preferences.path{1} = pathname;
            else
                n = height(files_preferences) + 1;
                files_preferences.file{n, 1} = filename;
                files_preferences.path{n, 1} = pathname;
            end
            
            if isstruct(files_preferences)
                app.UITable.Data = struct2table(files_preferences);
            else
                app.UITable.Data = files_preferences;
            end
            app.UITable.ColumnName = fieldnames(files_preferences);

        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            files_preferences = app.UITable.Data;
            [index, ~] = listdlg('ListString', files_preferences.file);
            files_preferences(index, :) = [];
            app.UITable.Data = files_preferences;
            
        end

        % Button pushed function: DistributePreferencesButton
        function DistributePreferencesButtonPushed(app, event)
            files = app.TableFiles.Data;
            files_prf = app.UITable.Data;

            if isempty(files_prf)
                printLog(app, '014', '')
                return
            end
            status = [];
            ind2 = 1;
            for i = 1:height(files_prf)
                ind = find(contains(files.name, files_prf.file{i}(1:end - 4)));
                recording_last = '';
                for j = 1:length(ind)
                    ind_miqus = strfind(files.name{ind(j)}, 'Miqus');
                    recording = files.name{ind(j)}(1:ind_miqus - 2);
                    if ~strcmp(recording, recording_last)
                        recording_last = recording;
                        status(ind2) = copyfile([files_prf.path{i} files_prf.file{i}], [app.Target.Value  files.folder{ind(j)}(length(app.Source.Value) + 1:end) '\' recording]);
                        ind2 = ind2 + 1;
                    end
                end
            end

            printLog(app, '015', num2str(sum(status)/length(status)*100))

        end

        % Button pushed function: AssessFrameCountsButton
        function AssessFrameCountsButtonPushed(app, event)
            
            printLog(app, '016')

            files = app.TableFiles.Data;

            if isempty(files)
                printLog(app, '003', '')
                return
            end
            
            files.Frame_Count = zeros(height(files), 1);
            for i = 1:height(files)
                
                vidObj = VideoReader([files.folder{i} '\' files.name{i}]);
                n = 0;
                while hasFrame(vidObj)
                    readFrame(vidObj);
                    n = n + 1;
                end
                files.Frame_Count(i) = n;

                if rem(i, 10) == 0
                    printLog(app, '017', [num2str(i) ' of ' num2str(height(files)) ' file frame counts have been assessed'])
                    app.TableFiles.Data = files;
                    app.TableFiles.ColumnName = fieldnames(files);
                end

            end

            app.TableFiles.Data = files;
            app.TableFiles.ColumnName = fieldnames(files);

            ind_miqus = strfind(files.name, 'Miqus');
            for i = 1:length(ind_miqus)
                trials{i, 1} = files.name{i}(1:ind_miqus{i} - 1);
            end
            trials = unique(trials);
            frames_val.trials = {[]};
            frames_val.frames = {[]};
            ind = 1;
            for i = 1:length(trials)
                frames = files.Frame_Count(contains(files.name, trials{i}));
                if numel(unique(frames)) ~= 1
                    frames_val.trials{ind, 1} = trials{i};
                    frames_val.frames{ind, 1} = num2str(frames');
                    ind = ind + 1;
                end
            end

            app.TableFrameCount.Data = struct2table(frames_val);
            app.TableFrameCount.ColumnName = fieldnames(frames_val);

            printLog(app, '018')
        end

        % Value changed function: ExportAssessmentButton
        function ExportAssessmentButtonValueChanged(app, event)
            value = app.ExportAssessmentButton.Value;

            data = app.TableFiles.Data;
            [file, path] = uiputfile('.xlsx');
            writetable(data, [path file]);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Log = uitextarea(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ManagementTab = uitab(app.TabGroup);
            app.ManagementTab.Title = 'Management';
            app.SelectSourceDirectoryButton = uibutton(app.ManagementTab, 'push');
            app.SelectSourceDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSourceDirectoryButtonPushed, true);
            app.SelectSourceDirectoryButton.Text = '1 Select Source Directory';
            app.Source = uieditfield(app.ManagementTab, 'text');
            app.Source.Editable = 'off';
            app.SelectTargetDirectoryButton = uibutton(app.ManagementTab, 'push');
            app.SelectTargetDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTargetDirectoryButtonPushed, true);
            app.SelectTargetDirectoryButton.Text = '3 Select Target Directory';
            app.Target = uieditfield(app.ManagementTab, 'text');
            app.Target.Editable = 'off';
            app.FindVideoFilesButton = uibutton(app.ManagementTab, 'push');
            app.FindVideoFilesButton.ButtonPushedFcn = createCallbackFcn(app, @FindVideoFilesButtonPushed, true);
            app.FindVideoFilesButton.Text = '4 Find Video Files';
            app.TableFiles = uitable(app.ManagementTab);
            app.TableFiles.ColumnName = '';
            app.TableFiles.RowName = {};
            app.AssessTargetButton = uibutton(app.ManagementTab, 'push');
            app.AssessTargetButton.ButtonPushedFcn = createCallbackFcn(app, @AssessTargetButtonPushed, true);
            app.AssessTargetButton.Text = '5 Assess Target';
            app.TransferFilesButton = uibutton(app.ManagementTab, 'push');
            app.TransferFilesButton.ButtonPushedFcn = createCallbackFcn(app, @TransferFilesButtonPushed, true);
            app.TransferFilesButton.Text = '6 Transfer Files';
            app.TransferCalibrationFilesButton = uibutton(app.ManagementTab, 'push');
            app.TransferCalibrationFilesButton.ButtonPushedFcn = createCallbackFcn(app, @TransferCalibrationFilesButtonPushed, true);
            app.TransferCalibrationFilesButton.Text = '7 Transfer Calibration Files';
            app.SearchTagsExclude = uieditfield(app.ManagementTab, 'text');
            app.IncludeLabel = uilabel(app.ManagementTab);
            app.IncludeLabel.Text = 'Include';
            app.ExcludeLabel = uilabel(app.ManagementTab);
            app.ExcludeLabel.Text = 'Exclude';
            app.EditFieldLabel = uilabel(app.ManagementTab);
            app.EditFieldLabel.Text = {'2 Enter specific strings separated by'; 'commas to filter the files selected.'};
            app.SearchTagsInclude = uieditfield(app.ManagementTab, 'text');
            app.DistributePreferencesButton = uibutton(app.ManagementTab, 'push');
            app.DistributePreferencesButton.ButtonPushedFcn = createCallbackFcn(app, @DistributePreferencesButtonPushed, true);
            app.DistributePreferencesButton.Text = '8 Distribute Preferences';
            app.ExportAssessmentButton = uibutton(app.ManagementTab, 'state');
            app.ExportAssessmentButton.ValueChangedFcn = createCallbackFcn(app, @ExportAssessmentButtonValueChanged, true);
            app.ExportAssessmentButton.Text = 'Export Assessment';
            app.AssessmentTab = uitab(app.TabGroup);
            app.AssessmentTab.Title = 'Assessment';
            app.TableFrameCount = uitable(app.AssessmentTab);
            app.TableFrameCount.ColumnName = '';
            app.TableFrameCount.RowName = {};
            app.UnequalFrameCountLabel = uilabel(app.AssessmentTab);
            app.UnequalFrameCountLabel.Text = 'Unequal Frame Count';
            app.AssessFrameCountsButton = uibutton(app.AssessmentTab, 'push');
            app.AssessFrameCountsButton.ButtonPushedFcn = createCallbackFcn(app, @AssessFrameCountsButtonPushed, true);
            app.AssessFrameCountsButton.Text = 'Assess Frame Counts';
            app.Panel = uipanel(app.AssessmentTab);
            app.TableVideo = uitable(app.Panel);
            app.TableVideo.ColumnName = '';
            app.TableVideo.RowName = {};
            app.TableCalibration = uitable(app.Panel);
            app.TableCalibration.ColumnName = '';
            app.TableCalibration.RowName = {};
            app.TablePreferences = uitable(app.Panel);
            app.TablePreferences.ColumnName = '';
            app.TablePreferences.RowName = {};
            app.MissingVideoFilesLabel = uilabel(app.Panel);
            app.MissingVideoFilesLabel.Text = 'Missing Video Files';
            app.MissingCalibrationsLabel = uilabel(app.Panel);
            app.MissingCalibrationsLabel.Text = 'Missing Calibrations';
            app.MissingParameterFilesLabel = uilabel(app.Panel);
            app.MissingParameterFilesLabel.Text = 'Missing Parameter Files';
            app.StartGeneralAssessmentButton = uibutton(app.Panel, 'push');
            app.StartGeneralAssessmentButton.ButtonPushedFcn = createCallbackFcn(app, @StartGeneralAssessmentButtonPushed, true);
            app.StartGeneralAssessmentButton.Text = 'Start General Assessment';
            app.PreferencesTab = uitab(app.TabGroup);
            app.PreferencesTab.Title = 'Preferences';
            app.AddButton = uibutton(app.PreferencesTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = {'Add'; ''};
            app.RemoveButton = uibutton(app.PreferencesTab, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.UITable = uitable(app.PreferencesTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Label = uilabel(app.PreferencesTab);
            app.Label.Text = 'The file names of the selected preference files will be used as a tag to be distributed to recording folders.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TDM_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end